classdef Part2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ThisplotalreadyhasasmoothingfilterappliedLabel  matlab.ui.control.Label
        SetbothintervalstozerotoviewtheentireplotLabel  matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        QRSButton                      matlab.ui.control.Button
        MaximumIntervalEditField       matlab.ui.control.NumericEditField
        MaximumIntervalEditFieldLabel  matlab.ui.control.Label
        MinimumIntervalEditField       matlab.ui.control.NumericEditField
        MinimumIntervalEditFieldLabel  matlab.ui.control.Label
        RefreshButton                  matlab.ui.control.Button
        PatientDropDown                matlab.ui.control.DropDown
        PatientDropDownLabel           matlab.ui.control.Label
        BPMLabel                       matlab.ui.control.Label
        HRLabel                        matlab.ui.control.Label
        HeartRateLabel                 matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end

    
    properties (Access = private)
        X2Plot;
        Y2Plot;
        x3Plot;
        Names;
        Patient;
        xMax;
        xMin;
        it;
        QRS;
        ONE;
        Parse;
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            patients = dir('*.CSV');
             for n=1:length(patients)
                names{n} = patients(n).name;
            end
            d = readmatrix(patients(1).name);
            smoothECG = sgolayfilt(d(:,2),7,201);
            t = d(:,1)-min(d(:,1));
            b = find(t>0.799 & t<0.81000);
            o = find(t>0.999 & t<1.100); 
            parse = b(1);
            one = o(1);
            k=1:parse:131072;
            
            for i=1:(length(k)-1)
                m(i) = max(smoothECG(k(i):k(i+1)));
            end
            for j = 1:(length(m))
             h(j) = find(smoothECG == m(j));%get the indexes of the peaks
            end
            timepeaks2 = t(h);%find times from the peak indices
            %find the sum of the difference between peaks time and average
            avg2 = sum(diff(timepeaks2))/(length(k)-1);
            avgHR = 60/avg2 %get avg beats/ min
            app.Names = names;
            app.PatientDropDown.Items = app.Names;
            
            app.Parse = parse;
            app.ONE = one;
            app.it = 0;
            app.X2Plot = t;
            app.Y2Plot = smoothECG;
            plot(app.UIAxes,app.X2Plot,app.Y2Plot);
            legend(app.UIAxes,'MLII (mv)','V5 (mv)');
            app.HRLabel.Text = string(avgHR);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.QRS = 0;
            plot(app.UIAxes,app.X2Plot,app.Y2Plot);
            smoothECG = sgolayfilt(app.Y2Plot,7,201);

            legend(app.UIAxes,'MLII (mv)','V5 (mv)');
            if app.xMax>0
            axis(app.UIAxes,[app.xMin app.xMax min(app.Y2Plot)-0.5 max(app.Y2Plot)+0.5]);
            k=app.ONE*app.xMin:app.Parse:app.xMax*app.ONE;
            else 
            axis(app.UIAxes,[0 max(app.X2Plot) min(app.Y2Plot) max(app.Y2Plot)]); 
            k=1:app.Parse:131072;
            end
            for i=1:(length(k)-1)
                m(i) = max(smoothECG(k(i):k(i+1)));
            end
            for j = 1:(length(m))
             h(j) = find(smoothECG == m(j));%get the indexes of the peaks
            end
            timepeaks2 = app.X2Plot(h);%find times from the peak indices
            %find the sum of the difference between peaks time and average
            avg2 = sum(diff(timepeaks2))/(length(k)-2);
            avgHR = 60/avg2%get avg beats/ min
            app.HRLabel.Text = string(avgHR);
        end

        % Value changed function: PatientDropDown
        function PatientDropDownValueChanged(app, event)
            value = app.PatientDropDown.Value;
            d = readmatrix(value);
            smoothECG = sgolayfilt(d(:,2),7,201);
            t = d(:,1)-min(d(:,1));
            b = find(t>0.799 & t<0.81000);
            o = find(t>0.999 & t<1.100); 
            parse = b(1);
            one = o(1);
            k=1:parse:131072;
            
            
            for i=1:(length(k)-1)
                m(i) = max(smoothECG(k(i):k(i+1)));
            end
            for j = 1:(length(m))
             h(j) = find(smoothECG == m(j));%get the indexes of the peaks
            end
            timepeaks2 = t(h);%find times from the peak indices
            %find the sum of the difference between peaks time and average
            avg2 = sum(diff(timepeaks2))/(length(k)-1);
            avgHR = 60/avg2 %get avg beats/ min

            app.Parse = parse;
            app.ONE = one;
            app.X2Plot = t;
            app.Y2Plot = smoothECG;
            plot(app.UIAxes,app.X2Plot,app.Y2Plot);
            legend(app.UIAxes,'MLII (mv)','V5 (mv)');
            app.HRLabel.Text = string(avgHR);
        end

        % Value changed function: MinimumIntervalEditField
        function MinimumIntervalEditFieldValueChanged(app, event)
            value = app.MinimumIntervalEditField.Value;
            value = app.MinimumIntervalEditField.Value;
            app.xMin = value;
          
        end

        % Value changed function: MaximumIntervalEditField
        function MaximumIntervalEditFieldValueChanged(app, event)
            value = app.MaximumIntervalEditField.Value;
            value = app.MaximumIntervalEditField.Value;
            app.xMax = value;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
         app.it = app.it + 1;
        filename = "plot(" + app.it + ").png";
       exportgraphics(app.UIAxes,filename)  
        end

        % Button pushed function: QRSButton
        function QRSButtonPushed(app, event)
          app.QRS = 1;
          plot(app.UIAxes,app.X2Plot,app.Y2Plot);
          [~,locs_Rwave] = findpeaks(app.Y2Plot, 'MinPeakHeight',(max(app.Y2Plot)-2) ...
        ,'MinPeakDistance',app.Parse);
        ecgInverted = -app.Y2Plot;
        [~,locs_Swave] = findpeaks(ecgInverted, 'MinPeakHeight',-(max(ecgInverted)-2) ...
        ,'MinPeakDistance',app.Parse);

        plot(app.UIAxes,app.X2Plot,app.Y2Plot)
        hold(app.UIFigure.CurrentAxes,'on');
        plot(app.UIAxes,app.X2Plot(locs_Rwave),app.Y2Plot(locs_Rwave),'rv','MarkerFaceColor','r')
        hold(app.UIFigure.CurrentAxes,'on');
        plot(app.UIAxes,app.X2Plot(locs_Swave),app.Y2Plot(locs_Swave),'rs','MarkerFaceColor','b')
        hold(app.UIFigure.CurrentAxes,'off');
        legend(app.UIAxes,'MLII (mv)','R','S');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ECG vs Time')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'ECG (mv)')
            zlabel(app.UIAxes, 'Z')
            app.HeartRateLabel = uilabel(app.UIFigure);
            app.HeartRateLabel.Text = 'Heart Rate:';
            app.HRLabel = uilabel(app.UIFigure);
            app.HRLabel.Text = 'HR';
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = 'BPM';
            app.PatientDropDownLabel = uilabel(app.UIFigure);
            app.PatientDropDownLabel.Text = 'Patient:';
            app.PatientDropDown = uidropdown(app.UIFigure);
            app.PatientDropDown.ValueChangedFcn = createCallbackFcn(app, @PatientDropDownValueChanged, true);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.MinimumIntervalEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumIntervalEditFieldLabel.Text = 'Minimum Interval:';
            app.MinimumIntervalEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumIntervalEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumIntervalEditFieldValueChanged, true);
            app.MaximumIntervalEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumIntervalEditFieldLabel.Text = 'Maximum Interval:';
            app.MaximumIntervalEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumIntervalEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumIntervalEditFieldValueChanged, true);
            app.QRSButton = uibutton(app.UIFigure, 'push');
            app.QRSButton.ButtonPushedFcn = createCallbackFcn(app, @QRSButtonPushed, true);
            app.QRSButton.Text = 'QRS';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SetbothintervalstozerotoviewtheentireplotLabel = uilabel(app.UIFigure);
            app.SetbothintervalstozerotoviewtheentireplotLabel.Text = 'Set both intervals to zero to view the entire plot';
            app.ThisplotalreadyhasasmoothingfilterappliedLabel = uilabel(app.UIFigure);
            app.ThisplotalreadyhasasmoothingfilterappliedLabel.Text = 'This plot already has a smoothing filter applied';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Part2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end