classdef Part1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UsetherefreshbuttontoundothefilterorQRSoptionsLabel  matlab.ui.control.Label
        HeartRateLabel            matlab.ui.control.Label
        HRVLabel                  matlab.ui.control.Label
        Toviewtheentireplotsetbothintervalsto0Label  matlab.ui.control.Label
        QRSButton                 matlab.ui.control.Button
        FilterButton              matlab.ui.control.Button
        BPMLabel                  matlab.ui.control.Label
        MaximumIntervalEditField  matlab.ui.control.NumericEditField
        MaximumIntervalLabel      matlab.ui.control.Label
        MinimumIntervalEditField  matlab.ui.control.NumericEditField
        MinimumIntervalLabel      matlab.ui.control.Label
        SaveButton                matlab.ui.control.Button
        RefreshButton             matlab.ui.control.Button
        PatientNameDropDown       matlab.ui.control.DropDown
        PatientNameDropDownLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        ContextMenu               matlab.ui.container.ContextMenu
        Menu                      matlab.ui.container.Menu
        Menu2                     matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Patient;
        Names;
        x2plot;
        y2plot;
        y3plot;
        xMin;
        xMax;
        FS;
        it;
        QRS;% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            patient = [dir('*.info')];

            for n=1:length(patient)
                names{n} = erase(patient(n).name, '.info');
                dates{n} = patient(n).date;
            end
            [t,ecg,Fs] = LoadMITecg(names{1});
            smoothECG = sgolayfilt(ecg,7,201);
            
            k=1:290:650000;
            
            for i=1:(length(k)-1)
                m(i) = max(smoothECG(k(i):k(i+1)));
            end
            for j = 1:(length(m))
             h(j) = find(smoothECG == m(j));%get the indexes of the peaks
            end
            
            timepeaks2 = t(h);%find times from the peak indices
            %find the sum of the difference between peaks time and average
            avg2 = sum(diff(timepeaks2))/(length(k)-2);
            avgHR = 60/avg2%get avg beats/ min

            app.Patient = patient;
            app.Names = names;
            app.PatientNameDropDown.Items = app.Names;

            app.x2plot = t;
            app.y2plot = ecg;
            app.FS = Fs;
            plot(app.UIAxes,app.x2plot,app.y2plot);
            legend(app.UIAxes,'MLII (mv)','V5 (mv)');
            app.HRVLabel.Text = string(avgHR);
            app.it = 0;
        end

        % Value changed function: PatientNameDropDown
        function PatientNameDropDownValueChanged(app, event)
            value = app.PatientNameDropDown.Value;
            [app.x2plot,app.y2plot, app.FS] = LoadMITecg(value);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.QRS = 0;
            plot(app.UIAxes,app.x2plot,app.y2plot);
            smoothECG = sgolayfilt(app.y2plot,7,201);

            legend(app.UIAxes,'MLII (mv)','V5 (mv)');
            if app.xMax>0
            axis(app.UIAxes,[app.xMin app.xMax min(app.y2plot)-0.5 max(app.y2plot)+0.5]);
            k=360*app.xMin:290:app.xMax*360;
            
            else 
            axis(app.UIAxes,[0 2000 min(app.y2plot) max(app.y2plot)]); 
            k=1:290:650000;
            end
            for i=1:(length(k)-1)
                m(i) = max(smoothECG(k(i):k(i+1)));
            end
            for j = 1:(length(m))
             h(j) = find(smoothECG == m(j));%get the indexes of the peaks
            end
            timepeaks2 = app.x2plot(h);%find times from the peak indices
            %find the sum of the difference between peaks time and average
            avg2 = sum(diff(timepeaks2))/(length(k)-2);
            avgHR = 60/avg2%get avg beats/ min
            app.HRVLabel.Text = string(avgHR);
        end

        % Value changed function: MinimumIntervalEditField
        function MinimumIntervalEditFieldValueChanged(app, event)
            value = app.MinimumIntervalEditField.Value;
            app.xMin = value;
        end

        % Value changed function: MaximumIntervalEditField
        function MaximumIntervalEditFieldValueChanged(app, event)
            value = app.MaximumIntervalEditField.Value;
            app.xMax = value;
        end

        % Callback function
        function HeartRateEditFieldValueChanging(app, event)
            value = event.Value;
       
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
             ecgLP = lowpass(app.y2plot,4,app.FS);
            d = designfilt('bandstopiir','FilterOrder',2, ...
              'HalfPowerFrequency1',59,'HalfPowerFrequency2',61, ...
              'DesignMethod','butter','SampleRate',app.FS);
            app.y3plot = filtfilt(d,ecgLP);
            if app.QRS == 1
           plot(app.UIAxes,app.x2plot,app.y3plot);
          [~,locs_Rwave] = findpeaks(app.y3plot, 'MinPeakHeight',(max(app.y3plot)-2) ...
        ,'MinPeakDistance',290);
        ecgInverted = -app.y3plot;
        [~,locs_Swave] = findpeaks(ecgInverted, 'MinPeakHeight',-(max(ecgInverted)-2) ...
        ,'MinPeakDistance',290);

        plot(app.UIAxes,app.x2plot,app.y3plot)
        hold(app.UIFigure.CurrentAxes,'on');
        plot(app.UIAxes,app.x2plot(locs_Rwave),app.y3plot(locs_Rwave),'rv','MarkerFaceColor','r')
        hold(app.UIFigure.CurrentAxes,'on');
        plot(app.UIAxes,app.x2plot(locs_Swave),app.y3plot(locs_Swave),'rs','MarkerFaceColor','b')
        hold(app.UIFigure.CurrentAxes,'off');
        legend(app.UIAxes,'MLII (mv)','R','S');
            else
           plot(app.UIAxes,app.x2plot,app.y3plot);
           legend(app.UIAxes,'MLII (mv)','V5 (mv)');
            end
        end

        % Button pushed function: QRSButton
        function QRSButtonPushed(app, event)
         app.QRS = 1;
           plot(app.UIAxes,app.x2plot,app.y2plot);
          [~,locs_Rwave] = findpeaks(app.y2plot, 'MinPeakHeight',(max(app.y2plot)-2) ...
        ,'MinPeakDistance',290);
        ecgInverted = -app.y2plot;
        [~,locs_Swave] = findpeaks(ecgInverted, 'MinPeakHeight',-(max(ecgInverted)-2) ...
        ,'MinPeakDistance',290);

        plot(app.UIAxes,app.x2plot,app.y2plot)
        hold(app.UIFigure.CurrentAxes,'on');
        plot(app.UIAxes,app.x2plot(locs_Rwave),app.y2plot(locs_Rwave),'rv','MarkerFaceColor','r')
        hold(app.UIFigure.CurrentAxes,'on');
        plot(app.UIAxes,app.x2plot(locs_Swave),app.y2plot(locs_Swave),'rs','MarkerFaceColor','b')
        hold(app.UIFigure.CurrentAxes,'off');
        legend(app.UIAxes,'MLII (mv)','R','S');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
        app.it = app.it + 1;
        filename = "plot(" + app.it + ").png";
       exportgraphics(app.UIAxes,filename)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Heart Beat vs Time')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Heart Beat')
            zlabel(app.UIAxes, 'Z')
            app.PatientNameDropDownLabel = uilabel(app.UIFigure);
            app.PatientNameDropDownLabel.Text = 'Patient Name';
            app.PatientNameDropDown = uidropdown(app.UIFigure);
            app.PatientNameDropDown.ValueChangedFcn = createCallbackFcn(app, @PatientNameDropDownValueChanged, true);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MinimumIntervalLabel = uilabel(app.UIFigure);
            app.MinimumIntervalLabel.Text = 'Minimum Interval:';
            app.MinimumIntervalEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumIntervalEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumIntervalEditFieldValueChanged, true);
            app.MaximumIntervalLabel = uilabel(app.UIFigure);
            app.MaximumIntervalLabel.Text = 'Maximum Interval:';
            app.MaximumIntervalEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumIntervalEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumIntervalEditFieldValueChanged, true);
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = 'BPM';
            app.FilterButton = uibutton(app.UIFigure, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.QRSButton = uibutton(app.UIFigure, 'push');
            app.QRSButton.ButtonPushedFcn = createCallbackFcn(app, @QRSButtonPushed, true);
            app.QRSButton.Text = 'QRS';
            app.Toviewtheentireplotsetbothintervalsto0Label = uilabel(app.UIFigure);
            app.Toviewtheentireplotsetbothintervalsto0Label.Text = 'To view the entire plot set both intervals to 0';
            app.HRVLabel = uilabel(app.UIFigure);
            app.HRVLabel.Text = 'HRV';
            app.HeartRateLabel = uilabel(app.UIFigure);
            app.HeartRateLabel.Text = 'Heart Rate: ';
            app.UsetherefreshbuttontoundothefilterorQRSoptionsLabel = uilabel(app.UIFigure);
            app.UsetherefreshbuttontoundothefilterorQRSoptionsLabel.Text = 'Use the refresh button to undo the filter or QRS options';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Part1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end